classdef distributions_calculator_with_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        grpButton                 matlab.ui.container.ButtonGroup
        btnDistribution           matlab.ui.control.ToggleButton
        btnDensity                matlab.ui.control.ToggleButton
        txtValue2                 matlab.ui.control.NumericEditField
        lblValue2Label            matlab.ui.control.Label
        lblTitle                  matlab.ui.control.Label
        imgFormula                matlab.ui.control.Image
        btnCalculate              matlab.ui.control.Button
        txtValue3                 matlab.ui.control.NumericEditField
        lblValue3Label            matlab.ui.control.Label
        txtValue1                 matlab.ui.control.NumericEditField
        lblValue1Label            matlab.ui.control.Label
        cbxDistribution           matlab.ui.control.DropDown
        DistribucinDropDownLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        DistributionGraphUI       matlab.ui.control.UIAxes
        DensityGraphUI            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % inicialización default para Gaussiana
            app.lblValue1Label.Text = 'u';
            app.lblValue2Label.Text = 'Sigma';
            app.lblValue3Label.Text = 'x';
            
            app.lblTitle.Text = 'Densidad Gaussiana';
            % app.imgFormula.ImageSource = 'gaussiana-density'

            % app.txtValue3.Visible = "off";
            % app.lblValue3Label.Visible = "off";

        end

        % Callback function: LeftPanel, cbxDistribution
        function cbxDistributionOnChange(app, event)
            value = app.cbxDistribution.Value;
            selectedButton = app.grpButton.SelectedObject;

            switch value
                case 'Gaussiana'
                    app.lblValue1Label.Text = 'u';
                    app.lblValue2Label.Text = 'Sigma';
                    app.lblValue3Label.Text = 'x';
                    % app.txtValue3.Visible = "off";
                    % app.lblValue3Label.Visible = "off";

                                    % Verifica titulo de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución Gaussiana';
                    else
                        app.lblTitle.Text = 'Densidad Gaussiana';
                    end

                case 'Poisson'
                    % app.txtValue3.Visible = "on";
                    % app.lblValue3Label.Visible = "on";

                    app.lblValue1Label.Text = 'K mín';
                    app.lblValue2Label.Text = 'K Max';
                    app.lblValue3Label.Text = 'Lambda';

                    % Verifica titulo y img de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución de Poisson';
                        app.imgFormula.ImageSource = 'poisson-distribution.png';
                    else
                        app.lblTitle.Text = 'Densidad de Poisson';
                        app.imgFormula.ImageSource = 'poisson-density.png';
                    end
                case 'Binomial'
                    % contenido binomial
                case 'Uniforme'
                    % contenido uniforme
                case 'Exponencial'
                    % contenido exponencial
            end
        end

        % Button pushed function: btnCalculate
        function btnCalculateButtonPushed(app, event)
            
        distributionType = app.cbxDistribution.Value;

        switch distributionType
            case 'Gaussiana'
                mu = app.txtValue1.Value;
                sigma = app.txtValue2.Value;
                x = app.txtValue3.Value;
                
                % agregar 2 inputs más (?)
                % límite inferior, límite superior, número de puntos.
                x = linspace(0, 10, 20);

                % PDF

                pdf = (1/(sigma * sqrt(2*pi))) * exp(-(1/2)*((x - mu)/sigma).^2);

                bar(app.DensityGraphUI, x, pdf);
                title(app.DensityGraphUI, 'Función de Densidad de Probabilidad Gaussiana');
                xlabel(app.DensityGraphUI, 'x');
                ylabel(app.DensityGraphUI, 'f(x)');
                grid(app.DensityGraphUI, 'on');

            case 'Poisson'
                kMin = app.txtValue1.Value;
                kMax = app.txtValue2.Value;
                lambda = app.txtValue3.Value;
    
                k = kMin:kMax;

                % PDF

                pdf = exp(-lambda) * lambda.^k ./ factorial(k);
                
                bar(app.DensityGraphUI, k, pdf);
                title(app.DensityGraphUI, 'Función de Densidad de Probabilidad de Poisson');
                xlabel(app.DensityGraphUI, 'k');
                ylabel(app.DensityGraphUI, 'f(x) = P(X = k)');
                grid(app.DensityGraphUI, 'on');

                % cdf = zeros(size(kMax));
                % for i = 0:kMax
                %     cdf = cdf + pdf;
                % end

                % CDF

                cdf = zeros(size(k));
                for i = 1:length(k)
                    cdf(i) = sum(pdf(1:i));
                end
                
                stairs(app.DistributionGraphUI, k, cdf);
                title(app.DistributionGraphUI, 'Función de Distribución Acumulativa de Poisson');
                xlabel(app.DistributionGraphUI, 'x');
                ylabel(app.DistributionGraphUI, 'F(x) = P(X <= x)');
                grid(app.DistributionGraphUI, 'on');

            otherwise
                % Texto de error
        end

        end

        % Selection changed function: grpButton
        function grpButtonSelectionChanged(app, event)
            selectedButton = app.grpButton.SelectedObject;
            selectedOption = app.cbxDistribution.Value;

            if strcmp(selectedButton.Text, 'Distribución')
                switch selectedOption
                    case 'Gaussiana'
                        app.lblTitle.Text = 'Distribución Gaussiana';
                        % img gaussiana distribución
                    case 'Poisson'
                        app.lblTitle.Text = 'Distribución de Poisson';
                        app.imgFormula.ImageSource = 'poisson-distribution.png';
                    case 'Binomial'
                        app.lblTitle.Text = 'Distribución Binomial';
                        % img binomial distribución
                    case 'Uniforme'
                        app.lblTitle.Text = 'Distribución Uniforme';
                        % img uniforme distribución
                    case 'Exponencial'
                        app.lblTitle.Text = 'Distribución Exponencial';
                        % img exponencial distribución
                end
            else
                switch selectedOption
                    case 'Gaussiana'
                        app.lblTitle.Text = 'Densidad Gaussiana';
                        % img gaussiana densidad
                    case 'Poisson'
                        app.lblTitle.Text = 'Densidad de Poisson';
                        app.imgFormula.ImageSource = 'poisson-density.png';
                    case 'Binomial'
                        app.lblTitle.Text = 'Densidad Binomial';
                        % img binomial densidad
                    case 'Uniforme'
                        app.lblTitle.Text = 'Densidad Uniforme';
                        % img uniforme densidad
                    case 'Exponencial'
                        app.lblTitle.Text = 'Densidad Exponencial';
                        % img exponencial densidad
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @cbxDistributionOnChange, true);
            app.DistribucinDropDownLabel = uilabel(app.LeftPanel);
            app.DistribucinDropDownLabel.Text = 'Distribución';
            app.cbxDistribution = uidropdown(app.LeftPanel);
            app.cbxDistribution.Items = {'Gaussiana', 'Poisson', 'Binomial', 'Uniforme', 'Exponencial'};
            app.cbxDistribution.ValueChangedFcn = createCallbackFcn(app, @cbxDistributionOnChange, true);
            app.cbxDistribution.Tag = 'gauss';
            app.cbxDistribution.Value = 'Gaussiana';
            app.lblValue1Label = uilabel(app.LeftPanel);
            app.lblValue1Label.Text = 'lblValue1';
            app.txtValue1 = uieditfield(app.LeftPanel, 'numeric');
            app.txtValue1.Tag = 'miu';
            app.lblValue3Label = uilabel(app.LeftPanel);
            app.lblValue3Label.Text = 'lblValue3';
            app.txtValue3 = uieditfield(app.LeftPanel, 'numeric');
            app.txtValue3.Tag = 'sigma';
            app.btnCalculate = uibutton(app.LeftPanel, 'push');
            app.btnCalculate.ButtonPushedFcn = createCallbackFcn(app, @btnCalculateButtonPushed, true);
            app.btnCalculate.Text = 'Calcular';
            app.imgFormula = uiimage(app.LeftPanel);
            app.lblTitle = uilabel(app.LeftPanel);
            app.lblTitle.Text = 'lblTitle';
            app.lblValue2Label = uilabel(app.LeftPanel);
            app.lblValue2Label.Text = 'lblValue2';
            app.txtValue2 = uieditfield(app.LeftPanel, 'numeric');
            app.txtValue2.Tag = 'miu';
            app.grpButton = uibuttongroup(app.LeftPanel);
            app.grpButton.SelectionChangedFcn = createCallbackFcn(app, @grpButtonSelectionChanged, true);
            app.grpButton.Title = 'Función';
            app.btnDensity = uitogglebutton(app.grpButton);
            app.btnDensity.Text = 'Densidad';
            app.btnDensity.Value = true;
            app.btnDistribution = uitogglebutton(app.grpButton);
            app.btnDistribution.Text = 'Distribución';
            app.RightPanel = uipanel(app.GridLayout);
            app.DensityGraphUI = uiaxes(app.RightPanel);
            title(app.DensityGraphUI, 'f(x)')
            xlabel(app.DensityGraphUI, 'X')
            ylabel(app.DensityGraphUI, 'Y')
            app.DistributionGraphUI = uiaxes(app.RightPanel);
            title(app.DistributionGraphUI, 'F(x)')
            xlabel(app.DistributionGraphUI, 'X')
            ylabel(app.DistributionGraphUI, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = distributions_calculator_with_graph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end